classdef CarId_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        Untitled_1      matlab.ui.container.Menu
        Exit            matlab.ui.container.Menu
        ReStart         matlab.ui.container.Menu
        Untitled_2      matlab.ui.container.Menu
        Auther          matlab.ui.container.Menu
        Block           matlab.ui.container.Menu
        uipanel1        matlab.ui.container.Panel
        uibuttongroup1  matlab.ui.container.ButtonGroup
        openLocal       matlab.ui.control.Button
        checkbox1       matlab.ui.control.CheckBox
        text5           matlab.ui.control.Label
        axes1           matlab.ui.control.UIAxes
        uipanel2        matlab.ui.container.Panel
        uibuttongroup2  matlab.ui.container.ButtonGroup
        btnGray1        matlab.ui.control.Button
        btnEdge         matlab.ui.control.Button
        edit2           matlab.ui.control.EditField
        text6           matlab.ui.control.Label
        uibuttongroup3  matlab.ui.container.ButtonGroup
        btnFushi        matlab.ui.control.Button
        btnSoft         matlab.ui.control.Button
        btnRemove       matlab.ui.control.Button
        btnCut          matlab.ui.control.Button
        edit3           matlab.ui.control.EditField
        text7           matlab.ui.control.Label
        text12          matlab.ui.control.Label
        edit4           matlab.ui.control.EditField
        edit5           matlab.ui.control.EditField
        text13          matlab.ui.control.Label
        re_split        matlab.ui.control.Button
        uibuttongroup4  matlab.ui.container.ButtonGroup
        btnGray2        matlab.ui.control.Button
        btnBalance      matlab.ui.control.Button
        btnDouble       matlab.ui.control.Button
        btnMid          matlab.ui.control.Button
        btnCut2         matlab.ui.control.Button
        btnSelect       matlab.ui.control.Button
        edit6           matlab.ui.control.EditField
        edit8           matlab.ui.control.EditField
        edit9           matlab.ui.control.EditField
        remove2         matlab.ui.control.Button
        edit11          matlab.ui.control.EditField
        text14          matlab.ui.control.Label
        text15          matlab.ui.control.Label
        text18          matlab.ui.control.Label
        text16          matlab.ui.control.Label
        text11          matlab.ui.control.Label
        text9           matlab.ui.control.Label
        text10          matlab.ui.control.Label
        uipanel3        matlab.ui.container.Panel
        axes2           matlab.ui.control.UIAxes
        axes3           matlab.ui.control.UIAxes
        axes4           matlab.ui.control.UIAxes
        axes5           matlab.ui.control.UIAxes
        axes6           matlab.ui.control.UIAxes
        axes7           matlab.ui.control.UIAxes
        axes8           matlab.ui.control.UIAxes
        axes9           matlab.ui.control.UIAxes
        axes10          matlab.ui.control.UIAxes
        axes11          matlab.ui.control.UIAxes
        axes12          matlab.ui.control.UIAxes
        axes14          matlab.ui.control.UIAxes
        uipanel5        matlab.ui.container.Panel
        text19          matlab.ui.control.Label
        edit12          matlab.ui.control.EditField
        axes15          matlab.ui.control.UIAxes
        uipanel6        matlab.ui.container.Panel
        axes16          matlab.ui.control.UIAxes
        uipanel7        matlab.ui.container.Panel
        CPMATCH         matlab.ui.control.Button
        MAKECODE        matlab.ui.control.Button
    end

    
    methods (Access = private)
        function edit10_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function uibuttongroup5_SelectionChangedFcn(app, hObject, eventdata, handles)
             strr=get(hObject,'tag');
            switch strr
                case 'radiobutton1'
                      handles.algorithm=1;
            
                case 'radiobutton2'
                      handles.algorithm=2;
            end
             guidata(hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CarId_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CarId (see VARARGIN)
            
            % Choose default command line output for CarId
            handles.output = hObject;
            
            
            %隐藏坐标轴
            set(handles.axes1,'visible','off');
            set(handles.axes2,'visible','off');
            set(handles.axes3,'visible','off');
            set(handles.axes4,'visible','off');
            set(handles.axes5,'visible','off');
            set(handles.axes6,'visible','off');
            set(handles.axes7,'visible','off');
            set(handles.axes8,'visible','off');
            set(handles.axes9,'visible','off');
            set(handles.axes10,'visible','off');
            set(handles.axes11,'visible','off');
            set(handles.axes12,'visible','off');
            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: CPMATCH
        function CPMATCH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
              CodeID=handles.CodeID;
              [IDCP,IDNUM,IDNAME]=IDFind(CodeID);
               handles.IDCP=IDCP;
               handles.IDNUM=IDNUM;
               handles.IDNAME=IDNAME;
            guidata(hObject, handles);
        end

        % Menu selected function: Exit
        function Exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(gcf)
        end

        % Button pushed function: MAKECODE
        function MAKECODE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % 生成QR二维码
            [QRIMG,decode_message] = QRcode(handles.IDNUM);
                  axes(handles.axes15)     %将Tag值为axes1的坐标轴置为当前
                  imshow(QRIMG,[]);   %解决图像太大无法显示的问题.
                  TUTI=['1',handles.IDNUM, handles.IDNAME]
                  title(TUTI);
                  set(handles.edit12,'string',decode_message)
                  figure,imshow(QRIMG,[]),title(TUTI)
            % 生成灰度二维码
                  [grayimg] = graycode(handles.IDNUM);
                  axes(handles.axes16)     %将Tag值为axes1的坐标轴置为当前
                  imshow(grayimg,[]);   %解决图像太大无法显示的问题.
                  title(TUTI);
                  figure,imshow(grayimg,[]),title(TUTI)
            guidata(hObject, handles);
        end

        % Button pushed function: btnBalance
        function btnBalance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                I=handles.gray2;
            % histeq 使用直方图均衡增强对比度
            % J = histeq(I,n) 变换灰度图像 I，以使输出灰度图像 J 具有 n 个 bin 的直方图大致平坦。
            % 当 n 远小于 I 中的离散灰度级数时，J 的直方图更平坦。
                if handles.FunctionSwitch==1
                  edit6_a=get(handles.edit6,'string');
                  edit6_aa=double(str2double(edit6_a));
                  I = histeq(I,edit6_aa);
                  axes(handles.axes9)     %将Tag值为axes1的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题.
                  title('车牌识别直方图均衡化');
                  figure,subplot(1,2,1),imhist(handles.gray2);title('灰度直方图');
                  subplot(1,2,2),imhist(I);title('均衡化后的直方图');
                else
                  axes(handles.axes9)     %将Tag值为axes1的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题.
                  title('跳过直方图均衡化');
                end
                handles.balance=I;     %更新原图
            
            guidata(hObject, handles);
        end

        % Button pushed function: btnCut2
        function btnCut2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            I=handles.Mid;
            I = my_imsplit(I);  %切割图像
            [m, n] = size(I);
            
            s = sum(I);    %sum(x)就是竖向相加，求每列的和，结果是行向量;
            j = 1;
            k1 = 1;
            k2 = 1;
            while j ~= n
                while s(j) == 0
                    j = j + 1;
                end
                k1 = j;
                while s(j) ~= 0 && j <= n-1
                    j = j + 1;
                end
                k2 = j + 1;
                if k2 - k1 > round(n / 6.5)
                    [val, num] = min(sum(I(:, [k1+5:k2-5])));
                    I(:, k1+num+5) = 0;
                end
            end
            
            y1 = 10;   %10
            y2 = 0.05;  %0.25
            flag = 0;
            word1 = [];
            while flag == 0
                [m, n] = size(I);
                left = 1;
                width = 0;
                while sum(I(:, width+1)) ~= 0
                    width = width + 1;
                end
                if width < y1
                    I(:, [1:width]) = 0;
                    I = my_imsplit(I);
                else
                    temp = my_imsplit(imcrop(I, [1,1,width,m]));
                    [m, n] = size(temp);
                    all = sum(sum(temp));
                    two_thirds=sum(sum(temp([round(m/3):2*round(m/3)],:)));
                    if two_thirds/all > y2
                        flag = 1;
                        word1 = temp;
                    end
                    I(:, [1:width]) = 0;
                    I = my_imsplit(I);
                end
            end
            
             % 分割出第二个字符
             [word2,I]=getword(I);
             % 分割出第三个字符
             [word3,I]=getword(I);
             % 分割出第四个字符
             [word4,I]=getword(I);
             % 分割出第五个字符
             [word5,I]=getword(I);
             % 分割出第六个字符
             [word6,I]=getword(I);
             % 分割出第七个字符
             [word7,I]=getword(I);
            
             % 新能源汽车加一个字符
             if handles.numberplate_color==3
              [word8,I]=getword(I);
             end
            
             figure;
            
             word1=imresize(word1,[40 20]);%imresize对图像做缩放处理，常用调用格式为：B=imresize(A,ntimes,method)；其中method可选nearest,bilinear（双线性）,bicubic,box,lanczors2,lanczors3等
             word2=imresize(word2,[40 20]);
             word3=imresize(word3,[40 20]);
             word4=imresize(word4,[40 20]);
             word5=imresize(word5,[40 20]);
             word6=imresize(word6,[40 20]);
             word7=imresize(word7,[40 20]);
            
            
            if handles.numberplate_color==3
               word8=imresize(word8,[40 20]);
            end
            
             subplot(5,8,17),imshow(word1),title('1');
             subplot(5,8,18),imshow(word2),title('2');
             subplot(5,8,19),imshow(word3),title('3');
             subplot(5,8,20),imshow(word4),title('4');
             subplot(5,8,21),imshow(word5),title('5');
             subplot(5,8,22),imshow(word6),title('6');
             subplot(5,8,23),imshow(word7),title('7');
            
            
            if handles.numberplate_color==3
               subplot(5,8,24),imshow(word8),title('8');
            end
            
             imwrite(word1,'1.jpg'); % 创建七位车牌字符图像
             imwrite(word2,'2.jpg');
             imwrite(word3,'3.jpg');
             imwrite(word4,'4.jpg');
             imwrite(word5,'5.jpg');
             imwrite(word6,'6.jpg');
             imwrite(word7,'7.jpg');
            
            
            if handles.numberplate_color==3
              imwrite(word8,'8.jpg');
            end
        end

        % Button pushed function: btnCut
        function btnCut_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            img7=handles.Remove;
            
            [y, x, z] = size(img7);
            img8 = double(img7);    % 转成双精度浮点型
            
            % 车牌的蓝色区域
            % Y方向
            blue_Y = zeros(y, 1);
            for i = 1:y
                for j = 1:x
                    if(img8(i, j) == 1) % 判断车牌位置区域
                        blue_Y(i, 1) = blue_Y(i, 1) + 1;    % 像素点统计
                    end
                end
            end
            
            % 找到Y坐标的最小值
            img_Y1 = 1;
            while (blue_Y(img_Y1) < 5) && (img_Y1 < y)
                img_Y1 = img_Y1 + 1;
            end
            
            % 找到Y坐标的最大值
            img_Y2 = y;
            while (blue_Y(img_Y2) < 5) && (img_Y2 > img_Y1)
                img_Y2 = img_Y2 - 1;
            end
            
            % x方向
            blue_X = zeros(1, x);
            for j = 1:x
                for i = 1:y
                    if(img8(i, j) == 1) % 判断车牌位置区域
                        blue_X(1, j) = blue_X(1, j) + 1;
                    end
                end
            end
            
            % 找到x坐标的最小值
            img_X1 = 1;
            while (blue_X(1, img_X1) < 5) && (img_X1 < x)
                img_X1 = img_X1 + 1;
            end
            
            % 找到x坐标的最小值
            img_X2 = x;
            while (blue_X(1, img_X2) < 5) && (img_X2 > img_X1)
                img_X2 = img_X2 - 1;
            end
            
            % 对图像进行裁剪
            img9 = handles.I(img_Y1:img_Y2, img_X1:img_X2, :);
            axes(handles.axes7)     %将Tag值为axes1的坐标轴置为当前
            imshow(img9,[]);   %解决图像太大无法显示的问题.
            title('图像裁剪');
            % 保存提取出来的车牌图像
            imwrite(img9, '车牌图像.jpg');
            handles.Cut=img9;     %更新原图


            % 车牌颜色识别
            
            % 分离RGB通道
            r = img9(:,:,1);
            g = img9(:,:,2);
            b = img9(:,:,3);
            
            % 计算各通道的平均值
            mean_r = mean(r(:));
            mean_g = mean(g(:));
            mean_b = mean(b(:));
            
            % 根据平均值进行颜色分类
            if mean_b > mean_g && mean_b > mean_r
                numberplate_color=1;
                disp('车牌颜色：蓝色');
                msgbox('车牌颜色：蓝色','识别出的车牌颜色')
            elseif mean_g > mean_b && mean_g > mean_r
                numberplate_color=3;
                disp('车牌颜色：绿色');
                msgbox('车牌颜色：绿色','识别出的车牌颜色')
            else
                numberplate_color=2;
                disp('车牌颜色：黄色');
                msgbox('车牌颜色：黄色','识别出的车牌颜色')
            end
            
            
            handles.numberplate_color=numberplate_color;
            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: btnDouble
        function btnDouble_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                I=handles.balance;
             % imbinarize函数 通过阈值化将二维灰度图像或三维体二值化
             %通过将所有高于全局阈值的值替换为 1 并将所有其他值设置为 0，从二维或三维灰度图像 I 创建二值图像。
             % 默认情况下，imbinarize 使用 Otsu 方法，该方法选择特定阈值来最小化阈值化的黑白像素的类内方差
             % imbinarize(I,T) 使用阈值 T 从图像 I 创建二值图像。T 可以是指定为标量亮度值的全局图像阈值，
             % 也可以是指定为亮度值矩阵的局部自适应阈值
                edit8_a=get(handles.edit8,'string');
                edit8_aa=double(str2double(edit8_a));
            
                % 非蓝色车牌图像取反
                if handles.numberplate_color~=1
                   I=imcomplement(I);
                end
            
                I = imbinarize(I, edit8_aa);
                axes(handles.axes10)     %将Tag值为axes1的坐标轴置为当前
                imshow(I,[]);   %解决图像太大无法显示的问题.
                title('车牌识别图像二值化');
                handles.double=I;     %更新原图
            
            guidata(hObject, handles);
        end

        % Button pushed function: btnEdge
        function btnEdge_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                I=handles.gray1;
            %     关于edge
            %     指定要检测的边缘的方向。Sobel 和 Prewitt 方法可以检测垂直方向和/或水平方向的边缘。
            %     Roberts 方法可以检测与水平方向成 45 度角和/或 135 度角的边缘。
            %     第三个参数为二值化阈值，把比该值大的置为1
            %     仅当 method 是 'Sobel'、'Prewitt' 或 'Roberts' 时，此语法才有效。
                if handles.FunctionSwitch==1
                   edit2_a=get(handles.edit2,'string');
                   edit2_aa=str2double(edit2_a);
                   I = edge(I, 'Sobel', edit2_aa, 'both');
                   axes(handles.axes3)     %将Tag值为axes1的坐标轴置为当前
                   imshow(I,[]);   %解决图像太大无法显示的问题.
                   title('预处理边缘检测');
                else
                   axes(handles.axes3)     %将Tag值为axes1的坐标轴置为当前
                   imshow(I,[]);   %解决图像太大无法显示的问题.
                   title('跳过边缘检测');
                end
                    handles.edge=I;     %更新原图
                   % Update handles structure
            
            guidata(hObject, handles);
        end

        % Button pushed function: btnFushi
        function btnFushi_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                I=handles.edge;
            % 关于imerode() 腐蚀图像
            % J = imerode(I,SE) 腐蚀灰度图像、二值图像或压缩二值图像 I，返回腐蚀图像 J。
            % SE 是结构元素对象或结构元素对象的数组，由 strel 或 offsetstrel 函数返回。
                if handles.FunctionSwitch==1
                  %se=[1;1;1];
                  edit3_a=get(handles.edit3,'string');
                  edit3_aa=round(str2double(edit3_a));
                  SE = strel('rectangle',[edit3_aa,1]);
                  I = imerode(I, SE);
                  axes(handles.axes4)     %将Tag值为axes1的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题
                  title('车牌定位图像腐蚀');
                else
                  axes(handles.axes4)     %将Tag值为axes1的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题
                  title('跳过图像腐蚀');
                end
                handles.Fushi=I;     %更新原图
            guidata(hObject, handles);
        end

        % Button pushed function: btnGray1
        function btnGray1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                I=handles.I;
                I = rgb2gray(I);
                axes(handles.axes2)     %将Tag值为axes1的坐标轴置为当前
                imshow(I,[]);           %解决图像太大无法显示的问题
                title('预灰度处理');
                figure,imhist(I);title('灰度图直方图');
                handles.gray1=I;     %更新原图
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: btnGray2
        function btnGray2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
                I=handles.Cut;
                I = rgb2gray(I);
                axes(handles.axes8)     %将Tag值为axes1的坐标轴置为当前
                imshow(I,[]);   %解决图像太大无法显示的问题.
                title('车牌识别灰度处理');
                handles.gray2=I;     %更新原图
            
            guidata(hObject, handles);
        end

        % Button pushed function: btnMid
        function btnMid_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                I=handles.Remove2;
            % medfilt2 二维中位数滤波
            % J = medfilt2(I,[m n]) 执行中位数滤波，
            % 其中每个输出像素包含输入图像中对应像素周围的 m×n 邻域中的中位数值。
                if handles.FunctionSwitch==1
                  edit9_a=get(handles.edit9,'string');
                  edit9_aa=double(str2double(edit9_a));
                  I = medfilt2(I,[3 edit9_aa]);
                  I = medfilt2(I,[edit9_aa 3 ]);
                  axes(handles.axes12)     %将Tag值为axes1的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题.
                  imwrite(I,"ZZLB.jpg")
                  title('车牌识别中值滤波');
                else
                  axes(handles.axes12)     %将Tag值为axes1的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题.
                  title('跳过中值滤波');
                end
                handles.Mid=I;     %更新原图
            
            guidata(hObject, handles);
        end

        % Button pushed function: btnRemove
        function btnRemove_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % bwareaopen 函数可以用于 从二值图像中删除小对象
            % 格式 BW2 = bwareaopen(BW,P) 从二值图像 BW 中删除少于 P 个像素的所有连通分量（对象），
            % 并生成另一个二值图像 BW2。此运算称为面积开运算。
                I=handles.Soft;
                if handles.FunctionSwitch==1
                  edit5_a=get(handles.edit5,'string');
                  edit5_aa=double(str2double(edit5_a)) ;
                  I = bwareaopen(I, edit5_aa);
                  axes(handles.axes6)     %将Tag值为axes6的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题.
                  title('车牌定位移除对象');
                else
                  axes(handles.axes6)     %将Tag值为axes6的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题.
                  title('跳过移除对象');
                end
                handles.Remove=I;     %更新原图
            
            guidata(hObject, handles);
        end

        % Button pushed function: btnSelect
        function btnSelect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
             liccode=char(['0':'9' 'A':'H' 'J':'N' 'P':'Z'  '鲁京粤']);%建立自动识别字符代码表；'鲁
             %liccode=char(['0':'9' 'A':'Z' '京辽鲁陕苏豫浙贵']);%建立自动识别字符代码表；'京津沪渝港澳吉辽鲁豫冀鄂湘晋青皖苏赣浙闽粤琼台陕甘云川贵黑藏蒙桂新宁'
             subBw2 = zeros(40, 20);
             num = 1;   % 车牌位数
            
             % 判断车牌位数
             if handles.numberplate_color==3
               car_size=8;
             else
               car_size=7;
             end
            
             for i = 1:car_size
                ii = int2str(i);    % 将整型数据转换为字符串型数据
                word = imread([ii,'.jpg']); % 读取之前分割出的字符的图片
                segBw2 = imresize(word, [40,20], 'nearest');    % 调整图片的大小
                segBw2 = imbinarize(segBw2, 0.5);    % 图像二值化
                if i == 1   % 字符第一位为汉字，定位汉字所在字段
                    kMin = 35;
                    kMax = 37;
                elseif i == 2   % 第二位为英文字母，定位字母所在字段
                    kMin = 11;
                    kMax = 34;
                elseif i >= 3   % 第三位开始就是数字或字母了，定位数字和字母所在字段
                    kMin = 1;
                    kMax = 34;
                end
            
                l = 1;
                for k = kMin : kMax
                    fname = strcat('model3\',liccode(k),'.jpg');  % 根据字符库找到图片模板
                    samBw2 = imread(fname); % 读取模板库中的图片
                    samBw2 = imbinarize(samBw2, 0.5);    % 图像二值化
            
                    % 将待识别图片与模板图片做差
                    for i1 = 1:40
                        for j1 = 1:20
                            subBw2(i1, j1) = segBw2(i1, j1) - samBw2(i1 ,j1);
                        end
                    end
            
                    % 统计两幅图片不同点的个数，并保存下来
                    Dmax = 0;
                    for i2 = 1:40
                        for j2 = 1:20
                            if subBw2(i2, j2) ~= 0
                                Dmax = Dmax + 1;
                            end
                        end
                    end
                    error(l) = Dmax;
                    l = l + 1;
                end
            
                % 找到图片差别最少的图像
                errorMin = min(error);
                findc = find(error == errorMin);
            %     error
            %     findc
            
                % 根据字库，对应到识别的字符
                Code(num) = liccode(findc(1) + kMin - 1);
                num = num + 1;
             end
             handles.CodeID=Code;
             axes(handles.axes14)     %将Tag值为axes1的坐标轴置为当前
             imshow(handles.Cut,[]);   %解决图像太大无法显示的问题.
             title('车牌');
            
             % 显示识别结果
            
             if handles.FunctionSwitch==1
               [IDCP,IDNUM,IDNAME]=IDFind(Code);
               msgbox(IDCP,'识别出的车牌号');
               disp(IDCP);
             else
               msgbox(Code,'识别出的车牌号');
               disp(IDCP);
             end
             guidata(hObject, handles);
        end

        % Button pushed function: btnSoft
        function btnSoft_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                I=handles.Fushi;
             % imclose函数，即闭运算：用结构元素对图像先膨胀，再腐蚀。
             % 闭运算可以用来融合窄的缺口和细长的弯口，去掉小洞，填补轮廓上的缝隙
             % 本部分的结构元素采用一个大小为 [n n] 的矩形结构
                if handles.FunctionSwitch==1
                   edit4_a=get(handles.edit4,'string');
                   edit4_aa=round(str2double(edit4_a));
                   se = strel('rectangle', [edit4_aa, edit4_aa]);
                   I = imclose(I, se);     %使用闭运算进行平滑处理
                   axes(handles.axes5)     %将Tag值为axes1的坐标轴置为当前
                   imshow(I,[]);   %解决图像太大无法显示的问题
                   title('车牌定位平滑处理');
                else
                   axes(handles.axes5)     %将Tag值为axes1的坐标轴置为当前
                   imshow(I,[]);   %解决图像太大无法显示的问题
                   title('跳过平滑处理');
                end
                handles.Soft=I;     %更新原图
            
            guidata(hObject, handles);
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if ( get(hObject,'Value') )
            handles.FunctionSwitch= 1;   %#ok<*NASGU>
            else
            handles.FunctionSwitch= 0;
            end
            guidata(hObject, handles);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            guidata(hObject, handles);
        end

        % Button pushed function: openLocal
        function openLocal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %关于uigetfile
            %打开一个模态对话框，其中列出了当前文件夹中的文件。用户可以在这里选择或输入文件的名称。如果文件存在并且有效，当用户点击打开时，uigetfile
            %将返回文件名。如果用户点击取消或窗口关闭按钮 (X)，uigetfile 将返回 0。
            [filename, pathname]=uigetfile({'*.jpg; *.png; *.bmp; *.tif';'*.png';'All Image Files';'*.*'},'请选择图片路径');
            if pathname==0
                return;
            end
            I=imread([pathname filename]);
            %显示原图
                axes(handles.axes1)     %将Tag值为axes1的坐标轴置为当前
                imshow(I,[]);   %解决图像太大无法显示的问题.why?貌似会自动缩放
                title('原图');
                handles.I=I;        %更新图像
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: re_split
        function re_split_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                I=handles.Cut;
                handles.I=I;     %更新原图
            guidata(hObject, handles);
        end

        % Button pushed function: remove2
        function remove2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % bwareaopen 函数可以用于 从二值图像中删除小对象
            % 格式 BW2 = bwareaopen(BW,P) 从二值图像 BW 中删除少于 P 个像素的所有连通分量（对象），
            % 并生成另一个二值图像 BW2。此运算称为面积开运算。
                I=handles.double;
                if handles.FunctionSwitch==1
                  edit11_a=get(handles.edit11,'string');
                  edit11_aa=double(str2double(edit11_a)) ;
                  I = bwareaopen(I, edit11_aa);
                  axes(handles.axes11)     %将Tag值为axes6的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题.
                  title('车牌识别移除对象');
                else
                  axes(handles.axes11)     %将Tag值为axes6的坐标轴置为当前
                  imshow(I,[]);   %解决图像太大无法显示的问题.
                  title('跳过移除对象');
                end
                handles.Remove2=I;     %更新原图
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CarId';
            app.figure1.Tag = 'figure1';
            app.Untitled_1 = uimenu(app.figure1);
            app.Untitled_1.Text = '开始';
            app.Untitled_1.Tag = 'Untitled_1';
            app.Exit = uimenu(app.Untitled_1);
            app.Exit.MenuSelectedFcn = createCallbackFcn(app, @Exit_Callback, true);
            app.Exit.Checked = 'on';
            app.Exit.Text = '退出';
            app.Exit.Tag = 'Exit';
            app.ReStart = uimenu(app.Untitled_1);
            app.ReStart.Checked = 'on';
            app.ReStart.Text = '重启';
            app.ReStart.Tag = 'ReStart';
            app.Untitled_2 = uimenu(app.figure1);
            app.Untitled_2.Text = '帮助';
            app.Untitled_2.Tag = 'Untitled_2';
            app.Auther = uimenu(app.Untitled_2);
            app.Auther.Checked = 'on';
            app.Auther.Text = '联系作者';
            app.Auther.Tag = 'Auther';
            app.Block = uimenu(app.Untitled_2);
            app.Block.Checked = 'on';
            app.Block.Text = '作者博客';
            app.Block.Tag = 'Block';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '车牌识别';
            app.uipanel1.Tag = 'uipanel1';
            app.uibuttongroup1 = uibuttongroup(app.uipanel1);
            app.uibuttongroup1.Title = '获取图像';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.openLocal = uibutton(app.uibuttongroup1, 'push');
            app.openLocal.ButtonPushedFcn = createCallbackFcn(app, @openLocal_Callback, true);
            app.openLocal.Tag = 'openLocal';
            app.openLocal.Text = '打开本地图片';
            app.checkbox1 = uicheckbox(app.uibuttongroup1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = '功能开关';
            app.text5 = uilabel(app.uibuttongroup1);
            app.text5.Tag = 'text5';
            app.text5.Text = '↑记得勾选一下';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = '图像处理';
            app.uipanel2.Tag = 'uipanel2';
            app.uibuttongroup2 = uibuttongroup(app.uipanel2);
            app.uibuttongroup2.Title = '图像预处理';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.btnGray1 = uibutton(app.uibuttongroup2, 'push');
            app.btnGray1.ButtonPushedFcn = createCallbackFcn(app, @btnGray1_Callback, true);
            app.btnGray1.Tag = 'btnGray1';
            app.btnGray1.Text = '灰度处理';
            app.btnEdge = uibutton(app.uibuttongroup2, 'push');
            app.btnEdge.ButtonPushedFcn = createCallbackFcn(app, @btnEdge_Callback, true);
            app.btnEdge.Tag = 'btnEdge';
            app.btnEdge.Text = '边缘检测';
            app.edit2 = uieditfield(app.uibuttongroup2, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Tag = 'edit2';
            app.edit2.Value = '0.13';
            app.text6 = uilabel(app.uibuttongroup2);
            app.text6.Tag = 'text6';
            app.text6.Text = '边缘检测阈值设定';
            app.uibuttongroup3 = uibuttongroup(app.uipanel2);
            app.uibuttongroup3.Title = '车牌定位';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.btnFushi = uibutton(app.uibuttongroup3, 'push');
            app.btnFushi.ButtonPushedFcn = createCallbackFcn(app, @btnFushi_Callback, true);
            app.btnFushi.Tag = 'btnFushi';
            app.btnFushi.Text = '图像腐蚀';
            app.btnSoft = uibutton(app.uibuttongroup3, 'push');
            app.btnSoft.ButtonPushedFcn = createCallbackFcn(app, @btnSoft_Callback, true);
            app.btnSoft.Tag = 'btnSoft';
            app.btnSoft.Text = '平滑处理';
            app.btnRemove = uibutton(app.uibuttongroup3, 'push');
            app.btnRemove.ButtonPushedFcn = createCallbackFcn(app, @btnRemove_Callback, true);
            app.btnRemove.Tag = 'btnRemove';
            app.btnRemove.Text = '移除对象';
            app.btnCut = uibutton(app.uibuttongroup3, 'push');
            app.btnCut.ButtonPushedFcn = createCallbackFcn(app, @btnCut_Callback, true);
            app.btnCut.Tag = 'btnCut';
            app.btnCut.Text = '定位剪切';
            app.edit3 = uieditfield(app.uibuttongroup3, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '6';
            app.text7 = uilabel(app.uibuttongroup3);
            app.text7.Tag = 'text7';
            app.text7.Text = '图像腐蚀结构元素大小设定：';
            app.text12 = uilabel(app.uibuttongroup3);
            app.text12.Tag = 'text12';
            app.text12.Text = '平滑处理结构元素大小设定：';
            app.edit4 = uieditfield(app.uibuttongroup3, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '220';
            app.edit5 = uieditfield(app.uibuttongroup3, 'text');
            app.edit5.Tag = 'edit5';
            app.edit5.Value = '2200';
            app.text13 = uilabel(app.uibuttongroup3);
            app.text13.Tag = 'text13';
            app.text13.Text = '移除对象连通像素临界值设定：';
            app.re_split = uibutton(app.uibuttongroup3, 'push');
            app.re_split.ButtonPushedFcn = createCallbackFcn(app, @re_split_Callback, true);
            app.re_split.Tag = 're_split';
            app.re_split.Text = '重新裁剪';
            app.uibuttongroup4 = uibuttongroup(app.uipanel2);
            app.uibuttongroup4.Title = '车牌识别';
            app.uibuttongroup4.Tag = 'uibuttongroup4';
            app.btnGray2 = uibutton(app.uibuttongroup4, 'push');
            app.btnGray2.ButtonPushedFcn = createCallbackFcn(app, @btnGray2_Callback, true);
            app.btnGray2.Tag = 'btnGray2';
            app.btnGray2.Text = '灰度处理';
            app.btnBalance = uibutton(app.uibuttongroup4, 'push');
            app.btnBalance.ButtonPushedFcn = createCallbackFcn(app, @btnBalance_Callback, true);
            app.btnBalance.Tag = 'btnBalance';
            app.btnBalance.Text = '直方图均衡化';
            app.btnDouble = uibutton(app.uibuttongroup4, 'push');
            app.btnDouble.ButtonPushedFcn = createCallbackFcn(app, @btnDouble_Callback, true);
            app.btnDouble.Tag = 'btnDouble';
            app.btnDouble.Text = '二值化图像';
            app.btnMid = uibutton(app.uibuttongroup4, 'push');
            app.btnMid.ButtonPushedFcn = createCallbackFcn(app, @btnMid_Callback, true);
            app.btnMid.Tag = 'btnMid';
            app.btnMid.Text = '中值滤波';
            app.btnCut2 = uibutton(app.uibuttongroup4, 'push');
            app.btnCut2.ButtonPushedFcn = createCallbackFcn(app, @btnCut2_Callback, true);
            app.btnCut2.Tag = 'btnCut2';
            app.btnCut2.Text = '图像切割';
            app.btnSelect = uibutton(app.uibuttongroup4, 'push');
            app.btnSelect.ButtonPushedFcn = createCallbackFcn(app, @btnSelect_Callback, true);
            app.btnSelect.Tag = 'btnSelect';
            app.btnSelect.Text = '模板匹配';
            app.edit6 = uieditfield(app.uibuttongroup4, 'text');
            app.edit6.Tag = 'edit6';
            app.edit6.Value = '64';
            app.edit8 = uieditfield(app.uibuttongroup4, 'text');
            app.edit8.Tag = 'edit8';
            app.edit8.Value = '0.76';
            app.edit9 = uieditfield(app.uibuttongroup4, 'text');
            app.edit9.Tag = 'edit9';
            app.edit9.Value = '1';
            app.remove2 = uibutton(app.uibuttongroup4, 'push');
            app.remove2.ButtonPushedFcn = createCallbackFcn(app, @remove2_Callback, true);
            app.remove2.Tag = 'remove2';
            app.remove2.Text = '移除对象';
            app.edit11 = uieditfield(app.uibuttongroup4, 'text');
            app.edit11.Tag = 'edit11';
            app.edit11.Value = '600';
            app.text14 = uilabel(app.uibuttongroup4);
            app.text14.Tag = 'text14';
            app.text14.Text = '直方图均衡化bin值设定:';
            app.text15 = uilabel(app.uibuttongroup4);
            app.text15.Tag = 'text15';
            app.text15.Text = '二值化图像阈值设定：';
            app.text18 = uilabel(app.uibuttongroup4);
            app.text18.Tag = 'text18';
            app.text18.Text = '移除对象连通像素阈值设定：';
            app.text16 = uilabel(app.uibuttongroup4);
            app.text16.Tag = 'text16';
            app.text16.Text = '中值滤波模板大小设定：';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = '手持摄像设备的车牌自动识别技术';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = '版本V3.0';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = '星火燎原团队自主研发';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = '结果';
            app.uipanel3.Tag = 'uipanel3';
            app.axes2 = uiaxes(app.uipanel3);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.uipanel3);
            app.axes3.Tag = 'axes3';
            app.axes4 = uiaxes(app.uipanel3);
            app.axes4.Tag = 'axes4';
            app.axes5 = uiaxes(app.uipanel3);
            app.axes5.Tag = 'axes5';
            app.axes6 = uiaxes(app.uipanel3);
            app.axes6.Tag = 'axes6';
            app.axes7 = uiaxes(app.uipanel3);
            app.axes7.Tag = 'axes7';
            app.axes8 = uiaxes(app.uipanel3);
            app.axes8.Tag = 'axes8';
            app.axes9 = uiaxes(app.uipanel3);
            app.axes9.Tag = 'axes9';
            app.axes10 = uiaxes(app.uipanel3);
            app.axes10.Tag = 'axes10';
            app.axes11 = uiaxes(app.uipanel3);
            app.axes11.Tag = 'axes11';
            app.axes12 = uiaxes(app.uipanel3);
            app.axes12.Tag = 'axes12';
            app.axes14 = uiaxes(app.uipanel3);
            app.axes14.Tag = 'axes14';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'QR二维码';
            app.uipanel5.Tag = 'uipanel5';
            app.text19 = uilabel(app.uipanel5);
            app.text19.Tag = 'text19';
            app.text19.Text = '解码后得到的信息：';
            app.edit12 = uieditfield(app.uipanel5, 'text');
            app.edit12.Tag = 'edit12';
            app.edit12.Value = 'xxxxxxxxx';
            app.axes15 = uiaxes(app.uipanel5);
            app.axes15.Tag = 'axes15';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = '灰度二维码';
            app.uipanel6.Tag = 'uipanel6';
            app.axes16 = uiaxes(app.uipanel6);
            app.axes16.Tag = 'axes16';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = '结果转换';
            app.uipanel7.Tag = 'uipanel7';
            app.CPMATCH = uibutton(app.uipanel7, 'push');
            app.CPMATCH.ButtonPushedFcn = createCallbackFcn(app, @CPMATCH_Callback, true);
            app.CPMATCH.Tag = 'CPMATCH';
            app.CPMATCH.Text = '车牌模板匹配';
            app.MAKECODE = uibutton(app.uipanel7, 'push');
            app.MAKECODE.ButtonPushedFcn = createCallbackFcn(app, @MAKECODE_Callback, true);
            app.MAKECODE.Tag = 'MAKECODE';
            app.MAKECODE.Text = '生成二维码';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarId_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CarId_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end